classdef dimmer_board_control_panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DimmerAppUIFigure             matlab.ui.Figure
        PortDropDown                  matlab.ui.control.DropDown
        PortDropDownLabel             matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        DimmerboardcontrolpanelLabel  matlab.ui.control.Label
        Firingangle0degKnob           matlab.ui.control.Knob
        Label                         matlab.ui.control.Label
    end

    properties (Access = private)
        SerialPortHandler             internal.Serialport
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PortDropDown.Items = serialportlist("available");
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.SerialPortHandler)
                app.SerialPortHandler = serialport(app.PortDropDown.Value, 115200);
            end     
        end

        % Value changed function: Firingangle0degKnob
        function Firingangle0degKnobValueChanged(app, event)
            value = app.Firingangle0degKnob.Value;
            app.Label.Text = sprintf("Firing angle: %3d deg ", round(value)); 
            if ~isempty(app.SerialPortHandler)
                ctrlMsg = sprintf("%03d\n", round(value));
                write(app.SerialPortHandler, ctrlMsg, "char"); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DimmerAppUIFigure = uifigure('Visible', 'off');
            app.DimmerAppUIFigure.Name = 'Dimmer App';
            app.Label = uilabel(app.DimmerAppUIFigure);
            app.Label.Text = 'Firing angle: 0 deg';
            app.Firingangle0degKnob = uiknob(app.DimmerAppUIFigure, 'continuous');
            app.Firingangle0degKnob.Limits = [0 180];
            app.Firingangle0degKnob.ValueChangedFcn = createCallbackFcn(app, @Firingangle0degKnobValueChanged, true);
            app.DimmerboardcontrolpanelLabel = uilabel(app.DimmerAppUIFigure);
            app.DimmerboardcontrolpanelLabel.Text = {'Dimmer board '; 'control panel'};
            app.ConnectButton = uibutton(app.DimmerAppUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.DimmerAppUIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.DimmerAppUIFigure);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            % Show the figure after all components are created
            app.DimmerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dimmer_board_control_panel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DimmerAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DimmerAppUIFigure)
        end
    end
end